classdef ImageColorSegmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI                 matlab.ui.Figure
        ModifiedImage       matlab.ui.control.Image
        LoadedImage         matlab.ui.control.Image
        LoadButton          matlab.ui.control.Button
        SegmentationButton  matlab.ui.control.Button
        LoadLabel           matlab.ui.control.Label
        SegmentationLabel   matlab.ui.control.Label
        SaveButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        imagepath; % Full path to image
        modImg; % Modified image
        loadImg; %Loaded image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Hide unused elements at this stage
            app.LoadedImage.Visible = 'off';
            app.ModifiedImage.Visible = 'off';
            app.SaveButton.Enable = 'off';
            app.SegmentationButton.Enable = 'off';
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Load image and display it
            [file,path] = uigetfile({'*.jpg';'*.jpeg';'*.png'},'Wybierz plik');
            if isequal(file,0) == false
                app.imagepath = fullfile(path,file);
                app.LoadedImage.ImageSource = app.imagepath;
                app.loadImg = imread(app.imagepath);
                app.SegmentationButton.Enable = 'on';
                app.LoadedImage.Visible = 'on';
            end
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)
            % Trigger function that segments image
            app.modImg = segmentation(app.imagepath);
            app.ModifiedImage.ImageSource = repmat(app.modImg,1,1,3);
            app.ModifiedImage.Visible = 'on';
            app.SaveButton.Enable = 'on';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Save image in chosen location
            filter = {'*.jpg';'*.jpeg';'*.png'};
            [file, path] = uiputfile(filter,'Wybierz katalog');
            if isequal(file,0) == false
                imwrite(app.modImg,fullfile(path,file));
            end
        end

        % Image clicked function: LoadedImage
        function LoadedImageClicked(app, event)
            imshow(app.loadImg);
        end

        % Image clicked function: ModifiedImage
        function ModifiedImageClicked(app, event)
            imshow(app.modImg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure('Visible', 'off');
            app.GUI.Name = 'MATLAB App';
            app.ModifiedImage = uiimage(app.GUI);
            app.ModifiedImage.ImageClickedFcn = createCallbackFcn(app, @ModifiedImageClicked, true);
            app.LoadedImage = uiimage(app.GUI);
            app.LoadedImage.ImageClickedFcn = createCallbackFcn(app, @LoadedImageClicked, true);
            app.LoadButton = uibutton(app.GUI, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Załaduj obraz';
            app.SegmentationButton = uibutton(app.GUI, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Text = 'Segmentacja barwna';
            app.LoadLabel = uilabel(app.GUI);
            app.LoadLabel.Text = 'Tutaj możemy wczytać obraz';
            app.SegmentationLabel = uilabel(app.GUI);
            app.SegmentationLabel.Text = 'Tutaj możemy zrobić segmentację po wczytaniu';
            app.SaveButton = uibutton(app.GUI, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Zapisz obraz';
            % Show the figure after all components are created
            app.GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageColorSegmentation

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.GUI)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.GUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end